classdef appp < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                      matlab.ui.container.GridLayout
        ImplementationofIIRandFIRBandRejectFilterLabel  matlab.ui.control.Label
        PlayIIRFilteredAudioButton      matlab.ui.control.Button
        PlayFIRFilteredAudioButton      matlab.ui.control.Button
        PlayInputAudioButton            matlab.ui.control.Button
        Cutofffrequency2EditField       matlab.ui.control.NumericEditField
        Cutofffrequency2EditFieldLabel  matlab.ui.control.Label
        Cutofffrequency1EditField       matlab.ui.control.NumericEditField
        Cutofffrequency1EditFieldLabel  matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayInputAudioButton
        function PlayInputAudioButtonPushed(comp, event)


            [y,Fs]=audioread("C:\Users\jains\Downloads\ad2.wav");
            save press y Fs

filename = 'pp.wav';
audiowrite(filename,y,Fs);
clear y Fs 
[y,Fs] = audioread(filename);
sound(y,Fs)

fs = 8000;
nfft = 2048;
f = linspace(0,fs,nfft);
Y = abs(fft(y,nfft));

plot(comp.UIAxes3,f(1:nfft/2), Y(1:nfft/2));

t = linspace(0,length(y)/fs,length(y));
plot(comp.UIAxes6,t,y)
        end

        % Button pushed function: PlayFIRFilteredAudioButton
        function PlayFIRFilteredAudioButtonPushed(comp, event)
            [y,Fs]=audioread("C:\Users\jains\Downloads\ad2.wav");
save press y Fs

filename = 'pp.wav';
audiowrite(filename,y,Fs);
clear y Fs 
[y,Fs] = audioread(filename);

            bsFilt = designfilt('bandstopfir','FilterOrder',200, ...
         'CutoffFrequency1',comp.Cutofffrequency1EditField.Value,'CutoffFrequency2',comp.Cutofffrequency2EditField.Value, ...
         'SampleRate',8000);

dataIn = y,Fs;
dataOut2 = filter(bsFilt,dataIn);
sound(dataOut2,Fs)

fs = 8000;
nfft = 2048;
f = linspace(0,fs,nfft);
Y = abs(fft(dataOut2,nfft));

plot(comp.UIAxes,f(1:nfft/2), Y(1:nfft/2));

t = linspace(0,length(dataOut2)/fs,length(dataOut2));
plot(comp.UIAxes5,t,dataOut2)
        end

        % Value changed function: Cutofffrequency1EditField
        function Cutofffrequency1EditFieldValueChanged(comp, event)
            value = comp.Cutofffrequency1EditField.Value;
            
        end

        % Value changed function: Cutofffrequency2EditField
        function Cutofffrequency2EditFieldValueChanged(comp, event)
            value = comp.Cutofffrequency2EditField.Value;
            
        end

        % Button pushed function: PlayIIRFilteredAudioButton
        function PlayIIRFilteredAudioButtonPushed(comp, event)
         [y,Fs]=audioread("C:\Users\jains\Downloads\ad2.wav");
save press y Fs

filename = 'pp.wav';
audiowrite(filename,y,Fs);
clear y Fs 
[y,Fs] = audioread(filename);
   
            
            bsFilt = designfilt('bandstopiir','FilterOrder',60, ...
         'HalfPowerFrequency1',comp.Cutofffrequency1EditField.Value,'HalfPowerFrequency2',comp.Cutofffrequency2EditField.Value, ...
         'SampleRate',8000);

dataIn = y,Fs;
dataOut = filter(bsFilt,dataIn);
sound(dataOut,Fs)

fs = 8000;
nfft = 2048;
f = linspace(0,fs,nfft);
Y = abs(fft(dataOut,nfft));

plot(comp.UIAxes2,f(1:nfft/2), Y(1:nfft/2));

t = linspace(0,length(dataOut)/fs,length(dataOut));
plot(comp.UIAxes4,t,dataOut)


        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(comp, event)


        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(comp, event)
            
        end

        % Callback function
        function ButtonPushed(comp, event)
            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 865 613];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UIAxes6 = uiaxes(comp.GridLayout);
            title(comp.UIAxes6, 'Input')
            xlabel(comp.UIAxes6, 'Time')
            ylabel(comp.UIAxes6, 'Amplitude')
            zlabel(comp.UIAxes6, 'Z')
            comp.UIAxes5 = uiaxes(comp.GridLayout);
            title(comp.UIAxes5, 'FIR')
            xlabel(comp.UIAxes5, 'Time')
            ylabel(comp.UIAxes5, 'Amplitude')
            zlabel(comp.UIAxes5, 'Z')
            comp.UIAxes4 = uiaxes(comp.GridLayout);
            title(comp.UIAxes4, 'IIR')
            xlabel(comp.UIAxes4, 'Time')
            ylabel(comp.UIAxes4, 'Amplitude')
            zlabel(comp.UIAxes4, 'Z')
            comp.UIAxes = uiaxes(comp.GridLayout);
            title(comp.UIAxes, 'FIR Filtered Audio')
            xlabel(comp.UIAxes, 'Frequency')
            ylabel(comp.UIAxes, 'Magnitude')
            zlabel(comp.UIAxes, 'Z')
            comp.UIAxes2 = uiaxes(comp.GridLayout);
            title(comp.UIAxes2, 'IIR Filtered Audio')
            xlabel(comp.UIAxes2, 'Frequency')
            ylabel(comp.UIAxes2, 'Magnitude')
            zlabel(comp.UIAxes2, 'Z')
            comp.UIAxes2.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @UIAxes2ButtonDown, true);
            comp.UIAxes3 = uiaxes(comp.GridLayout);
            title(comp.UIAxes3, 'Input Audio')
            xlabel(comp.UIAxes3, 'Frequency')
            ylabel(comp.UIAxes3, 'Magnitude')
            zlabel(comp.UIAxes3, 'Z')
            comp.UIAxes3.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @UIAxes3ButtonDown, true);
            comp.Cutofffrequency1EditFieldLabel = uilabel(comp.GridLayout);
            comp.Cutofffrequency1EditFieldLabel.Text = 'Cut off frequency 1';
            comp.Cutofffrequency1EditField = uieditfield(comp.GridLayout, 'numeric');
            comp.Cutofffrequency1EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Cutofffrequency1EditFieldValueChanged, true);
            comp.Cutofffrequency2EditFieldLabel = uilabel(comp.GridLayout);
            comp.Cutofffrequency2EditFieldLabel.Text = 'Cut off frequency 2';
            comp.Cutofffrequency2EditField = uieditfield(comp.GridLayout, 'numeric');
            comp.Cutofffrequency2EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Cutofffrequency2EditFieldValueChanged, true);
            comp.PlayInputAudioButton = uibutton(comp.GridLayout, 'push');
            comp.PlayInputAudioButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @PlayInputAudioButtonPushed, true);
            comp.PlayInputAudioButton.Text = 'Play Input Audio';
            comp.PlayFIRFilteredAudioButton = uibutton(comp.GridLayout, 'push');
            comp.PlayFIRFilteredAudioButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @PlayFIRFilteredAudioButtonPushed, true);
            comp.PlayFIRFilteredAudioButton.Text = 'Play FIR Filtered Audio';
            comp.PlayIIRFilteredAudioButton = uibutton(comp.GridLayout, 'push');
            comp.PlayIIRFilteredAudioButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @PlayIIRFilteredAudioButtonPushed, true);
            comp.PlayIIRFilteredAudioButton.Text = 'Play IIR Filtered Audio';
            comp.ImplementationofIIRandFIRBandRejectFilterLabel = uilabel(comp.GridLayout);
            comp.ImplementationofIIRandFIRBandRejectFilterLabel.Text = 'Implementation of IIR and FIR Band Reject Filter';
        end
    end
end